classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SearchfileButton               matlab.ui.control.Button
        DetaliinodulTextArea           matlab.ui.control.TextArea
        DetaliinodulLabel              matlab.ui.control.Label
        NumelepozeialeseTextArea       matlab.ui.control.TextArea
        NumelepozeialeseTextAreaLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchfileButton
        function SearchfileButtonPushed(app, event)
            global imagel;
            [filename, pathimage] = uigetfile({'*.jpg'}, "Select File");
            fullpathimage = strcat(pathimage, filename);
            load nodulenet.mat
            load classifier.mat
            if(fullpathimage ~= "")
                imagel = imread(fullpathimage);
                app.Image.ImageSource = imagel;
                app.NumelepozeialeseTextArea.Value = filename;

                I1=im2double(imread(fullpathimage)); 
                I1thresh = I1 >= (10/255);
                sizeI = size(I1);
                zeros = floor((sizeI(2) -  min(sum(any(I1thresh))))/2);
                I2 = I1(:, zeros : sizeI(2)-zeros, :); 
                I2thresh = I1thresh(:, zeros : sizeI(2)-zeros, :);  

                sizeI2 = size(I2);
                zerosRows1 = floor((sizeI(1) -  min(sum(any(I2thresh, 2))))/2);
                if(zerosRows1 == 0)
                    zerosRows1 = 1;
                end
                I3 = I2(zerosRows1 : sizeI2(1)-zerosRows1, :, :);
             
                I3thresh = I3 >= (10/255); 
                sizeI1 = size(I3);
                zeros1 = floor((sizeI1(2) -  min(sum(any(I3thresh))))/2); 
                I4 = I3(:, zeros1 : sizeI1(2)-zeros1, :); 
                I4thresh = I3thresh(:, zeros1 : sizeI1(2)-zeros1, :);  

                sizeI4 = size(I4);
                zerosRows = floor((sizeI1(1) -  min(sum(any(I4thresh, 2))))/2); 
                if(zerosRows == 0)
                    zerosRows = 1;
                end
                I5 = I4(zerosRows : sizeI4(1)-zerosRows, :, :);

                imagel = I5;

                imagel = imresize(imagel, [224 224]);
                tic
                imageFeatures = activations(nodulenet, imagel, 'fc7');
                toc
                imageFeatures1 = squeeze(imageFeatures)';
                label = predict(classifier, imageFeatures1);

%                 label = classify(nodulenet, imagel)

               
	            switch(label)
                    case "2" 
                        app.DetaliinodulTextArea.Value = 'Conform scoului TIRADS, sansele de a fi un nodul canceros sunt mici, sub 40%';
                    case "5" 
                        app.DetaliinodulTextArea.Value = 'Conform scoului TIRADS, sansele de a fi un nodul cancer sunt mari, peste 60%';
                    otherwise 
                        app.DetaliinodulTextArea.Value = 'Nu avem informatii despre acest nodul';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.NumelepozeialeseTextAreaLabel = uilabel(app.UIFigure);
            app.NumelepozeialeseTextAreaLabel.Text = 'Numele pozei alese:';
            app.NumelepozeialeseTextArea = uitextarea(app.UIFigure);
            app.NumelepozeialeseTextArea.Editable = 'off';
            app.DetaliinodulLabel = uilabel(app.UIFigure);
            app.DetaliinodulLabel.Text = 'Detalii nodul:';
            app.DetaliinodulTextArea = uitextarea(app.UIFigure);
            app.DetaliinodulTextArea.Editable = 'off';
            app.SearchfileButton = uibutton(app.UIFigure, 'push');
            app.SearchfileButton.ButtonPushedFcn = createCallbackFcn(app, @SearchfileButtonPushed, true);
            app.SearchfileButton.Text = 'Search file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end